classdef appGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AplicaoCRUIFigure             matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TreinoTab                     matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        DadosPanel                    matlab.ui.container.Panel
        GridLayout_3                  matlab.ui.container.GridLayout
        DivisodeTesteEditField        matlab.ui.control.NumericEditField
        DiviodeTesteLabel             matlab.ui.control.Label
        DiviodeValidacaoEditField     matlab.ui.control.NumericEditField
        DiviodeValidaoLabel           matlab.ui.control.Label
        DivisodeTreinoEditField       matlab.ui.control.NumericEditField
        DivisodeTreinoLabel           matlab.ui.control.Label
        DropDown_Saida                matlab.ui.control.DropDown
        FunodeSaidaLabel              matlab.ui.control.Label
        DropDown_Ativacao             matlab.ui.control.DropDown
        FunodeAtivaoLabel             matlab.ui.control.Label
        FuncaoTreino                  matlab.ui.control.DropDown
        FunodeTreinoLabel             matlab.ui.control.Label
        NumNeuronios                  matlab.ui.control.EditField
        NmerodeNeurniosLabel          matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        ResultadosPanel               matlab.ui.container.Panel
        GridLayout_2                  matlab.ui.container.GridLayout
        tempoexecCRN                  matlab.ui.control.NumericEditField
        tempoexecLabel                matlab.ui.control.Label
        accuracyCRN                   matlab.ui.control.NumericEditField
        AccuracyGlobalLabel           matlab.ui.control.Label
        erroCRN                       matlab.ui.control.NumericEditField
        TLabel                        matlab.ui.control.Label
        TreinoButton                  matlab.ui.control.Button
        TesteTab                      matlab.ui.container.Tab
        GridLayout2_2                 matlab.ui.container.GridLayout
        GridLayout4_2                 matlab.ui.container.GridLayout
        IrbuscarRedeNeuronalTreinadaEditField  matlab.ui.control.EditField
        RedeTreinadaEditFieldLabel_2  matlab.ui.control.Label
        DadosPanel_2                  matlab.ui.container.Panel
        GridLayout_5                  matlab.ui.container.GridLayout
        PROTEditField                 matlab.ui.control.NumericEditField
        PROTLabel                     matlab.ui.control.Label
        CREAEditField                 matlab.ui.control.NumericEditField
        CREALabel                     matlab.ui.control.Label
        CHEEditField                  matlab.ui.control.NumericEditField
        CHELabel                      matlab.ui.control.Label
        GGTEditField                  matlab.ui.control.NumericEditField
        GGTLabel                      matlab.ui.control.Label
        CHOLEditField                 matlab.ui.control.NumericEditField
        CHOLLabel                     matlab.ui.control.Label
        BILEditField                  matlab.ui.control.NumericEditField
        BILLabel                      matlab.ui.control.Label
        ALTEditField                  matlab.ui.control.NumericEditField
        ALTLabel                      matlab.ui.control.Label
        ASTEditField                  matlab.ui.control.NumericEditField
        ASTLabel                      matlab.ui.control.Label
        SexEditField                  matlab.ui.control.EditField
        Label_4                       matlab.ui.control.Label
        ALPEditField                  matlab.ui.control.NumericEditField
        ALPLabel                      matlab.ui.control.Label
        AgeEditField                  matlab.ui.control.NumericEditField
        AgeLabel                      matlab.ui.control.Label
        ALBEditField                  matlab.ui.control.NumericEditField
        ALBLabel                      matlab.ui.control.Label
        GridLayout3_2                 matlab.ui.container.GridLayout
        CategoriaLabel                matlab.ui.control.Label
        ResultadoButton               matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        EditFieldLabel                matlab.ui.control.Label
        TesteButton                   matlab.ui.control.Button
        ResultadosPanel_2             matlab.ui.container.Panel
        GridLayout_4                  matlab.ui.container.GridLayout
        ErroARN                       matlab.ui.control.NumericEditField
        accuracyLabel                 matlab.ui.control.Label
        ErroTRN                       matlab.ui.control.NumericEditField
        TaxadeErroLabel               matlab.ui.control.Label
        TempoExecLabel                matlab.ui.control.Label
        TempodeExecRN                 matlab.ui.control.NumericEditField
        InfoTab                       matlab.ui.container.Tab
        HTML                          matlab.ui.control.HTML
        RelatrioButton                matlab.ui.control.Button
        SairButton                    matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SairMenuSelected(app, event)
            delete(app);
        end

        % Callback function
        function InfoMenuSelected(app, event)
            menuInfo;
        end

        % Button pushed function: TreinoButton
        function TreinoButtonPushed(app, event)
           originalColor = app.TreinoButton.BackgroundColor;
           app.TreinoButton.BackgroundColor = [1 0 0]; % Red

            numNeuro = str2double(strsplit(app.NumNeuronios.Value));
            if any(isnan(numNeuro))
                errordlg('Número de Neurónios Inválido','Erro');
            end    
            try 
                 [testeGlobal, tempoExec, erroGlobal] = treino_redes(numNeuro, ...
                     app.FuncaoTreino.Value,...
                     app.DropDown_Ativacao.Value, ...
                     app.DropDown_Saida.Value, ...
                     app.DivisodeTreinoEditField.Value, ...
                     app.DiviodeValidacaoEditField.Value, ...
                     app.DivisodeTesteEditField.Value);

                 app.erroCRN.Value = erroGlobal;
                 app.accuracyCRN.Value = testeGlobal;
                 app.tempoexecCRN.Value = tempoExec;
            catch
                errordlg('Erro no Treino','Erro');
            end

            app.TreinoButton.BackgroundColor = originalColor;
        end

        % Button pushed function: TesteButton
        function TesteButtonPushed(app, event)
            originalColor = app.TreinoButton.BackgroundColor;
           app.TreinoButton.BackgroundColor = [1 0 0]; % Red

            nomeConfig = app.IrbuscarRedeNeuronalTreinadaEditField.Value;
            try 
                 [testeGlobal, tempoExec,erroGlobal] = Teste_redes(nomeConfig, ...
                     '');
                 
                app.ErroTRN.Value = erroGlobal;
                app.ErroARN.Value = testeGlobal;
                app.TempodeExecRN.Value = tempoExec;
                
            catch
                errordlg('Erro no Teste','Erro');
            end
            app.TreinoButton.BackgroundColor = originalColor;
        end

        % Button pushed function: ResultadoButton
        function ResultadoButtonPushed(app, event)
            originalColor = app.ResultadoButton.BackgroundColor;
           app.ResultadoButton.BackgroundColor = [1 0 0]; % Red  
            
            nomeConfig = app.IrbuscarRedeNeuronalTreinadaEditField.Value;
              if strcmpi(app.SexEditField.Value,'F')
                    Sex = 1;
              else
                    Sex = 0;
              end
          
            try 
                dados(1) = app.AgeEditField.Value;
                dados(2) = Sex;
                dados(3) = app.ALBEditField.Value;
                dados(4) = app.ALPEditField.Value;
                dados(5) = app.ALTEditField.Value;
                dados(6) = app.ASTEditField.Value;
                dados(7) = app.BILEditField.Value;
                dados(8) = app.CHEEditField.Value;
                dados(9) = app.CHOLEditField.Value;
                dados(10) = app.CREAEditField.Value;
                dados(11) = app.GGTEditField.Value;
                dados(12) = app.PROTEditField.Value;
                [testeGlobal, tempoExec,erroGlobal, Cate] = Teste_redes(nomeConfig, ...
                     dados);
                 
                app.ErroTRN.Value = erroGlobal;
                app.ErroARN.Value = testeGlobal;
                app.TempodeExecRN.Value = tempoExec;
                resultado = '';
                switch Cate
                    case 0
                        resultado = 'Blood Donor';
                    case 1
                        resultado ='Suspect Blood Donor';
                    case 2
                        resultado = 'Hepatitis';
                    case 3
                        resultado = 'Fibrosis';
                    case 4
                        resultado = 'Cirrhosis';
                end
                app.EditField.Value = resultado; 
            catch
                errordlg('Erro no Resultado','Erro');
            end

             app.ResultadoButton.BackgroundColor = originalColor;
        end

        % Button pushed function: RelatrioButton
        function RelatrioButtonPushed(app, event)
            open("relatorioCR.pdf");  
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
                SN = questdlg("Tem a certeza que quer sair?","Pedido para sair","Sim","Cancelar","Sim");
            if strcmp(SN,"Sim")
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AplicaoCRUIFigure = uifigure('Visible', 'off');
            app.AplicaoCRUIFigure.Name = 'Aplicação CR';
            app.AplicaoCRUIFigure.Icon = fullfile(pathToMLAPP, 'logoApp.jpg');
            app.TabGroup = uitabgroup(app.AplicaoCRUIFigure);
            app.TreinoTab = uitab(app.TabGroup);
            app.TreinoTab.Title = 'Treino';
            app.GridLayout2 = uigridlayout(app.TreinoTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.TreinoButton = uibutton(app.GridLayout3, 'push');
            app.TreinoButton.ButtonPushedFcn = createCallbackFcn(app, @TreinoButtonPushed, true);
            app.TreinoButton.Text = 'Treino';
            app.ResultadosPanel = uipanel(app.GridLayout3);
            app.ResultadosPanel.Title = 'Resultados';
            app.GridLayout_2 = uigridlayout(app.ResultadosPanel);
            app.TLabel = uilabel(app.GridLayout_2);
            app.TLabel.Text = 'Taxa de Erro:';
            app.erroCRN = uieditfield(app.GridLayout_2, 'numeric');
            app.erroCRN.ValueDisplayFormat = '%.2f';
            app.AccuracyGlobalLabel = uilabel(app.GridLayout_2);
            app.AccuracyGlobalLabel.Text = 'Accuracy Global:';
            app.accuracyCRN = uieditfield(app.GridLayout_2, 'numeric');
            app.accuracyCRN.ValueDisplayFormat = '%.2f';
            app.tempoexecLabel = uilabel(app.GridLayout_2);
            app.tempoexecLabel.Text = 'Tempo de Execução:';
            app.tempoexecCRN = uieditfield(app.GridLayout_2, 'numeric');
            app.tempoexecCRN.ValueDisplayFormat = '%.2f';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.DadosPanel = uipanel(app.GridLayout4);
            app.DadosPanel.Title = 'Dados';
            app.GridLayout_3 = uigridlayout(app.DadosPanel);
            app.NmerodeNeurniosLabel = uilabel(app.GridLayout_3);
            app.NmerodeNeurniosLabel.Text = 'Número de Neurónios:';
            app.NumNeuronios = uieditfield(app.GridLayout_3, 'text');
            app.NumNeuronios.Value = '10';
            app.FunodeTreinoLabel = uilabel(app.GridLayout_3);
            app.FunodeTreinoLabel.Text = 'Função de Treino:';
            app.FuncaoTreino = uidropdown(app.GridLayout_3);
            app.FuncaoTreino.Items = {'trainlm', 'traingd', 'traingdx', 'traindm', 'trainbfg', 'trainscg'};
            app.FuncaoTreino.Value = 'trainlm';
            app.FunodeAtivaoLabel = uilabel(app.GridLayout_3);
            app.FunodeAtivaoLabel.Text = 'Função de Ativação:';
            app.DropDown_Ativacao = uidropdown(app.GridLayout_3);
            app.DropDown_Ativacao.Items = {'tansig', 'purelin', 'logsig', 'softmax'};
            app.DropDown_Ativacao.Value = 'tansig';
            app.FunodeSaidaLabel = uilabel(app.GridLayout_3);
            app.FunodeSaidaLabel.Text = 'Função de Saida:';
            app.DropDown_Saida = uidropdown(app.GridLayout_3);
            app.DropDown_Saida.Items = {'tansig', 'purelin', 'logsig', 'softmax'};
            app.DropDown_Saida.Value = 'tansig';
            app.DivisodeTreinoLabel = uilabel(app.GridLayout_3);
            app.DivisodeTreinoLabel.Text = 'Divisão de Treino:';
            app.DivisodeTreinoEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.DivisodeTreinoEditField.Limits = [0 1];
            app.DivisodeTreinoEditField.ValueDisplayFormat = '%.2f';
            app.DiviodeValidaoLabel = uilabel(app.GridLayout_3);
            app.DiviodeValidaoLabel.Text = 'Divisão de Validação:';
            app.DiviodeValidacaoEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.DiviodeValidacaoEditField.Limits = [0 1];
            app.DiviodeValidacaoEditField.ValueDisplayFormat = '%.2f';
            app.DiviodeTesteLabel = uilabel(app.GridLayout_3);
            app.DiviodeTesteLabel.Text = 'Divisão de Teste:';
            app.DivisodeTesteEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.DivisodeTesteEditField.Limits = [0 1];
            app.DivisodeTesteEditField.ValueDisplayFormat = '%.2f';
            app.TesteTab = uitab(app.TabGroup);
            app.TesteTab.Title = 'Teste';
            app.GridLayout2_2 = uigridlayout(app.TesteTab);
            app.GridLayout3_2 = uigridlayout(app.GridLayout2_2);
            app.ResultadosPanel_2 = uipanel(app.GridLayout3_2);
            app.ResultadosPanel_2.Title = 'Resultados';
            app.GridLayout_4 = uigridlayout(app.ResultadosPanel_2);
            app.TempodeExecRN = uieditfield(app.GridLayout_4, 'numeric');
            app.TempodeExecRN.ValueDisplayFormat = '%.2f';
            app.TempoExecLabel = uilabel(app.GridLayout_4);
            app.TempoExecLabel.Text = 'Tempo de Execução:';
            app.TaxadeErroLabel = uilabel(app.GridLayout_4);
            app.TaxadeErroLabel.Text = 'Taxa de Erro:';
            app.ErroTRN = uieditfield(app.GridLayout_4, 'numeric');
            app.ErroTRN.ValueDisplayFormat = '%.2f';
            app.accuracyLabel = uilabel(app.GridLayout_4);
            app.accuracyLabel.Text = 'Accuracy Global:';
            app.ErroARN = uieditfield(app.GridLayout_4, 'numeric');
            app.ErroARN.ValueDisplayFormat = '%.2f';
            app.TesteButton = uibutton(app.GridLayout3_2, 'push');
            app.TesteButton.ButtonPushedFcn = createCallbackFcn(app, @TesteButtonPushed, true);
            app.TesteButton.Text = 'Teste';
            app.EditFieldLabel = uilabel(app.GridLayout3_2);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.GridLayout3_2, 'text');
            app.ResultadoButton = uibutton(app.GridLayout3_2, 'push');
            app.ResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadoButtonPushed, true);
            app.ResultadoButton.Text = 'Resultado';
            app.CategoriaLabel = uilabel(app.GridLayout3_2);
            app.CategoriaLabel.Text = 'Categoria';
            app.GridLayout4_2 = uigridlayout(app.GridLayout2_2);
            app.DadosPanel_2 = uipanel(app.GridLayout4_2);
            app.DadosPanel_2.Title = 'Dados';
            app.GridLayout_5 = uigridlayout(app.DadosPanel_2);
            app.ALBLabel = uilabel(app.GridLayout_5);
            app.ALBLabel.Text = 'ALB:';
            app.ALBEditField = uieditfield(app.GridLayout_5, 'numeric');
            app.ALBEditField.Limits = [1 150];
            app.ALBEditField.ValueDisplayFormat = '%.2f';
            app.ALBEditField.Value = 41.6;
            app.AgeLabel = uilabel(app.GridLayout_5);
            app.AgeLabel.Text = 'Age:';
            app.AgeEditField = uieditfield(app.GridLayout_5, 'numeric');
            app.AgeEditField.Limits = [1 99];
            app.AgeEditField.Value = 54;
            app.ALPLabel = uilabel(app.GridLayout_5);
            app.ALPLabel.Text = 'ALP:';
            app.ALPEditField = uieditfield(app.GridLayout_5, 'numeric');
            app.ALPEditField.Limits = [1 150];
            app.ALPEditField.ValueDisplayFormat = '%.2f';
            app.ALPEditField.Value = 100.4;
            app.Label_4 = uilabel(app.GridLayout_5);
            app.Label_4.Text = 'Sex:';
            app.SexEditField = uieditfield(app.GridLayout_5, 'text');
            app.SexEditField.Placeholder = 'F ou M';
            app.SexEditField.Value = 'M';
            app.ASTLabel = uilabel(app.GridLayout_5);
            app.ASTLabel.Text = 'AST:';
            app.ASTEditField = uieditfield(app.GridLayout_5, 'numeric');
            app.ASTEditField.Limits = [1 500];
            app.ASTEditField.ValueDisplayFormat = '%.2f';
            app.ASTEditField.Value = 35.7;
            app.ALTLabel = uilabel(app.GridLayout_5);
            app.ALTLabel.Text = 'ALT:';
            app.ALTEditField = uieditfield(app.GridLayout_5, 'numeric');
            app.ALTEditField.Limits = [1 200];
            app.ALTEditField.ValueDisplayFormat = '%.2f';
            app.ALTEditField.Value = 51.8;
            app.BILLabel = uilabel(app.GridLayout_5);
            app.BILLabel.Text = 'BIL:';
            app.BILEditField = uieditfield(app.GridLayout_5, 'numeric');
            app.BILEditField.Limits = [1 150];
            app.BILEditField.ValueDisplayFormat = '%.2f';
            app.BILEditField.Value = 4.3;
            app.CHOLLabel = uilabel(app.GridLayout_5);
            app.CHOLLabel.Text = 'CHOL:';
            app.CHOLEditField = uieditfield(app.GridLayout_5, 'numeric');
            app.CHOLEditField.Limits = [1 150];
            app.CHOLEditField.ValueDisplayFormat = '%.2f';
            app.CHOLEditField.Value = 7.29;
            app.GGTLabel = uilabel(app.GridLayout_5);
            app.GGTLabel.Text = 'GGT:';
            app.GGTEditField = uieditfield(app.GridLayout_5, 'numeric');
            app.GGTEditField.Limits = [1 500];
            app.GGTEditField.ValueDisplayFormat = '%.2f';
            app.GGTEditField.Value = 82.6;
            app.CHELabel = uilabel(app.GridLayout_5);
            app.CHELabel.Text = 'CHE:';
            app.CHEEditField = uieditfield(app.GridLayout_5, 'numeric');
            app.CHEEditField.Limits = [1 150];
            app.CHEEditField.ValueDisplayFormat = '%.2f';
            app.CHEEditField.Value = 10.37;
            app.CREALabel = uilabel(app.GridLayout_5);
            app.CREALabel.Text = 'CREA:';
            app.CREAEditField = uieditfield(app.GridLayout_5, 'numeric');
            app.CREAEditField.Limits = [1 150];
            app.CREAEditField.ValueDisplayFormat = '%.2f';
            app.CREAEditField.Value = 92;
            app.PROTLabel = uilabel(app.GridLayout_5);
            app.PROTLabel.Text = 'PROT:';
            app.PROTEditField = uieditfield(app.GridLayout_5, 'numeric');
            app.PROTEditField.Limits = [1 150];
            app.PROTEditField.ValueDisplayFormat = '%.2f';
            app.PROTEditField.Value = 72.4;
            app.RedeTreinadaEditFieldLabel_2 = uilabel(app.GridLayout4_2);
            app.RedeTreinadaEditFieldLabel_2.Text = 'Rede Treinada: ';
            app.IrbuscarRedeNeuronalTreinadaEditField = uieditfield(app.GridLayout4_2, 'text');
            app.IrbuscarRedeNeuronalTreinadaEditField.Placeholder = 'treinoRede_config2.mat';
            app.IrbuscarRedeNeuronalTreinadaEditField.Value = 'treinoRede_config1.mat';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.Image = uiimage(app.InfoTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Matlab.png');
            app.SairButton = uibutton(app.InfoTab, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.RelatrioButton = uibutton(app.InfoTab, 'push');
            app.RelatrioButton.ButtonPushedFcn = createCallbackFcn(app, @RelatrioButtonPushed, true);
            app.RelatrioButton.Text = 'Relatório';
            app.HTML = uihtml(app.InfoTab);
            app.HTML.HTMLSource = '<body style="background-color:#65c7d6; color:white;"> <fieldset>  <legend>Nome</legend>  <label for="nome">Lara Filipa da Silva Bizarro<p>Diogo Rafael Abrantes Oliveira</label> </fieldset> <fieldset>  <legend>Número</legend>  <label for="nome">2021130066<p>2021146037</label> </fieldset> <fieldset>  <legend>Cadeira</legend>  <label for="nome">Conhecimento e Raciocínio</label></fieldset></body>';
            % Show the figure after all components are created
            app.AplicaoCRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AplicaoCRUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AplicaoCRUIFigure)
        end
    end
end